classdef President < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetLobbyButton              matlab.ui.control.Button
        Button_213                    matlab.ui.control.StateButton
        Button_212                    matlab.ui.control.StateButton
        Button_211                    matlab.ui.control.StateButton
        Button_210                    matlab.ui.control.StateButton
        Button_209                    matlab.ui.control.StateButton
        Button_208                    matlab.ui.control.StateButton
        Button_207                    matlab.ui.control.StateButton
        Button_206                    matlab.ui.control.StateButton
        Button_205                    matlab.ui.control.StateButton
        Button_204                    matlab.ui.control.StateButton
        Button_203                    matlab.ui.control.StateButton
        Button_202                    matlab.ui.control.StateButton
        Button_201                    matlab.ui.control.StateButton
        Button_1                      matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        Button_4                      matlab.ui.control.StateButton
        Button_113                    matlab.ui.control.StateButton
        Button_112                    matlab.ui.control.StateButton
        Button_111                    matlab.ui.control.StateButton
        Button_110                    matlab.ui.control.StateButton
        Button_109                    matlab.ui.control.StateButton
        Button_108                    matlab.ui.control.StateButton
        Button_107                    matlab.ui.control.StateButton
        Button_106                    matlab.ui.control.StateButton
        Button_105                    matlab.ui.control.StateButton
        Button_104                    matlab.ui.control.StateButton
        Button_103                    matlab.ui.control.StateButton
        Button_102                    matlab.ui.control.StateButton
        Button_101                    matlab.ui.control.StateButton
        ConfirmButton                 matlab.ui.control.Button
        HowManyPlayersEditField       matlab.ui.control.NumericEditField
        HowManyPlayersEditFieldLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        JoinGameButton                matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        RulesButton                   matlab.ui.control.Button
        Player3Label                  matlab.ui.control.Label
        Player1Label                  matlab.ui.control.Label
        Player2Label                  matlab.ui.control.Label
        Player4Label                  matlab.ui.control.Label
        LastHandPlayedxLabel          matlab.ui.control.Label
        WhosUpPlLabel                 matlab.ui.control.Label
        PlayCardsButton               matlab.ui.control.Button
        SkipTurnButton                matlab.ui.control.Button
        WhosUpBox                     matlab.ui.control.Image
        PresidentTitle                matlab.ui.control.Image
        Player3Box                    matlab.ui.control.Image
        Player1Box                    matlab.ui.control.Image
        Player4Box                    matlab.ui.control.Image
        Player2Box                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% General
        gameDeck = 0       % Array of all 52 Cards
        playerDecks         % MxN array with M = # of players & N = number of cards
        playedCards = 0     % Cards on the table
        turnCount = 1       % Tracks how many turns have passed
        playerTurn = 1      % Which player's turn is it? Based on playerPriority loop, not actual player number
        skipCount = 0        % # of turns skipped in a round

        numPlayers = 2        % number of player CHANGE
        gameState           % Is the round over?
        winPlayer = 0       % Who won the round
        playerWins          % Number of wins

        %% ThingSpeak
        isOnline = false        % Is the game happening online
        playerID
        duration = 1
        lobbyState = 0
        userAPIKey = '7XEAHMX5E7JA3NVM';
        channelID = 2368599;
        readKey = 'LLHWWTB3K780X5S1';
        writeKey = 'XH18T2Q11AQPS9PA';

    end
    
    methods (Access = public)
        %% Buttons
        % What is the image name of the card?
        function imageArray = CallImage(~, cardArray)
            imageArray = cardArray + ".png";
        end
        
        % Update the icon of button
        function [] = UpdateButtonIcons(app, buttonRange, inputDeck, whichImage)
            if whichImage
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = '';
                end
            else
                imageButton = CallImage(app, inputDeck);
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = imageButton(mod(i,100));
                end
            end
        end
        
        % Chagne Button Visability
        function [] = UpdateButtonVisible(app, buttonRange, state)
            for i = buttonRange
                buttonName = "Button_" + i;
                app.(buttonName).Visible = state;
            end
        end
        
        % Change Button Value
        function [] = UpdateButtonValue(app, buttonRange, state)
            for i = buttonRange
                buttonName = "Button_" + i;
                app.(buttonName).Value = state;
            end
         end

        % Check Button States
        function statesArray = CheckButtonStates(app, buttonRange)
            statesArray = zeros(1, length(buttonRange));
            for i = buttonRange
                buttonName = "Button_" + i;
                statesArray(mod(i,100)) = app.(buttonName).Value;
            end
            statesArray = logical(statesArray);
        end

        %% Increase Player Turn
        function [] = IncreasePlayerTurn(app)
            app.turnCount = app.turnCount + 1;
            app.playerTurn = mod(app.turnCount, app.numPlayers);
                if app.playerTurn == 0
                    app.playerTurn = app.numPlayers;
                end 
            fprintf("The player turn has been increased to: %g \n", app.playerTurn)

            % if app.isOnline
            %     pause(app.duration)
            %     thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 3, 'Values', app.numPlayers);
            % end

        end

        %% ThingSpeak
        % Send Array over ThingSpeak
        
        % Update ThingSpeak
        function [] = UpdateThingSpeak(app)
            fprintf(" ---> UpdatingThingSpeak ... ")
            pause(app.duration)

            data1 = app.lobbyState;
            data2 = string(num2str(app.gameDeck));
            data3 = string(num2str(app.playedCards));
            data4 = app.playerTurn;
            data5 = app.skipCount;

            thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 1:5, 'Values', [data1, data2, data3, data4, data5]);
            fprintf("All ThingSpeak fields have been updated! <--- \n")
        end


        % function [] = SendArray(app, fields, data)
        %     fprintf(" ---> (FUNC) Sending the following data: \n")
        %     disp(data)
        %     pause(app.duration)
        %     if length(data) > 1
        %         convert = string(num2str(data));
        %     else
        %         convert = data;
        %     end
        % 
        %     thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', fields, 'Values', convert);
        %     fprintf(" ---> (FUNC) Array data sent sucsessfully! \n")
        % end

        % Read Array data from ThingSpeak
        function output = ReadArray(app, fieldNum)
            dataOut = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', fieldNum, 'OutputFormat', 'Table');
            % disp(dataOut)
            indexedOutput = dataOut{1, 2};
            fprintf(" ---> Field %g has been read and has value type = ", fieldNum)
            % disp(indexedOutput)

            if iscell(indexedOutput)
                fprintf("cell")
                output = str2num(cell2mat(indexedOutput));
            elseif isnan(indexedOutput)
                fprintf("NaN")
                output = 0;  
            elseif isstring(indexedOutput)
                fprintf("str")
                output = str2num(indexedOutput);
            elseif isa(indexedOutput, "double")
                fprintf("double")
                output = indexedOutput;
            else 
                error("unexpected. Failed to convert")              
            end
            fprintf(" <--- \n")
        end

        function [] = PlayerTurnLock(app)
            app.playerTurn = ReadArray(app, 4);
            isMyTurn = app.playerTurn == app.playerID;
            if ~isMyTurn
                app.PlayCardsButton.Visible = "off";
                app.SkipTurnButton.Visible = "off";

                time = 0;
                while ~isMyTurn
                    time = time + 1;
                    fprintf("It has been %g seconds since your last turn \n", time)
                    app.playerTurn = ReadArray(app, 4);
                    isMyTurn = app.playerTurn == app.playerID;
                    pause(app.duration)
                end
                
                app.PlayCardsButton.Visible = "on";
                app.SkipTurnButton.Visible = "on";

                % Update Center Cards
                app.playedCards = ReadArray(app, 3);
                UpdateButtonIcons(app, 1:4, resize(app.playedCards, 4), false)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            fprintf("\nA new session of the game has been started! \n")
            
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on";
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.HomeButton.Visible = "off";
            app.HowManyPlayersEditField.Visible= "off";
            app.HowManyPlayersEditFieldLabel.Visible = "off";
            app.ConfirmButton.Visible ="off";
            app.ResetLobbyButton.Visible = "off";
            UpdateButtonVisible(app, [1:4, 101:113, 201:213], "off")

        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            RulesPopup
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            fprintf("Local game selected. Waiting for number of players... \n")
            app.isOnline = false;

            % drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on"; %% keep president image up 
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.PlayButton.Visible = "off";
            app.JoinGameButton.Visible = "off";
            app.HowManyPlayersEditField.Visible ="on";
            app.HowManyPlayersEditFieldLabel.Visible = "on";
            app.ConfirmButton.Visible ="on";
            app.ResetLobbyButton.Visible = "off";
            

        end

        % Button pushed function: JoinGameButton
        function JoinGameButtonPushed(app, event)
            fprintf("Online game intialized! Waiting for player information... \n")
            app.isOnline = true;
            % SendArray(app, 1, app.lobbyState)
            
            % This code removes old data from the ThingSpeak channel
            % url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(app.channelID), app.userAPIKey);
            % webwrite(url, weboptions('RequestMethod','delete'));
            
            %% Update UI
            % drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on"; %% keep president image up 
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.PlayButton.Visible = "off";
            app.JoinGameButton.Visible = "off";
            app.HowManyPlayersEditField.Visible ="on";
            app.HowManyPlayersEditFieldLabel.Visible = "on";
            app.ConfirmButton.Visible ="on";
            app.ResetLobbyButton.Visible = "on";

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Check if the game is happening online or local
            if app.isOnline
                %% Online Version
                app.playerID = app.HowManyPlayersEditField.Value;
                app.lobbyState = ReadArray(app, 1);
                fprintf("The current state of the lobby is %g \n", app.lobbyState)
                
                if app.playerID == 1 && ~app.lobbyState                 
                    % Update Variables before sending
                    fprintf("Player 1 has opened a new lobby! Variables updated and deck generated. Sending to ThingSpeak... \n")
                    app.lobbyState = true;
                    app.gameDeck = randperm(52);
                    UpdateThingSpeak(app)
                
                    % fprintf("LobbyState updated! Waiting to update playerTurn... \n")
                    % fprintf("It is currently Player's %g turn \n", app.playerTurn)
                    % fprintf("PlayerTurn updated! \n")
                    % 
                    % app.lobbyState = ReadArray(app, 1);
                    % fprintf("1 - The current state of the lobby is %g \n", app.lobbyState)
                    % app.lobbyState = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', 1)
                    % fprintf("2 - The current state of the lobby is %g \n", app.lobbyState)
         
                    % Generate Card Decks and Send
                    % app.cardNum = randperm(52);
                    % fprintf("Decks have been generated. Sending to ThingSpeak... \n")
                    % SendArray(app, 5, app.cardNum)
                    
                    % elseif app.playerID == 2 || app.playerID == 3 || app.playerID == 4
                    %     fprintf("Sucessfully joined lobby! Grabbing decks... \n")
                    %     app.cardNum = ReadArray(app, 5);
                    % else
                    %      fprintf("Player number not supported \n")
                    %      return
                    % end

                elseif app.lobbyState
                    if app.playerID == 2 || app.playerID == 3 || app.playerID == 4
                        fprintf("Sucessfully joined lobby! Grabbing deck data... \n")
                        app.gameDeck = ReadArray(app, 2);
                    else
                         fprintf("Player number not supported \n")
                         return
                    end
                else
                    fprintf("Waiting for player 1 to open lobby... \n")
                    return
                end
                
                % Process Cards
                app.playerDecks = zeros(app.numPlayers, 13);
                for i = 1:app.numPlayers
                    app.playerDecks(i, :) = app.gameDeck((13*i - 12): 13*i);
                    UpdateButtonIcons(app, (i*100) + (1:13), app.playerDecks(i,:), false);
                end
                fprintf("The following decks have been created: \n")
                disp(app.playerDecks)
                changeScene = true;
                

                % app.playerTurn = ReadArray(app, 4);
                % isMyTurn = app.playerTurn == app.playerID;
                % if ~isMyTurn
                %     app.PlayCardsButton.Visible = "off";
                %     app.SkipTurnButton.Visible = "off";
                %     while ~isMyTurn
                %         app.playerTurn = ReadArray(app, 4);
                %         pause(app.duration)
                %     end
                % 
                %     app.PlayCardsButton.Visible = "on";
                %     app.SkipTurnButton.Visible = "on";
                % 
                %     % Update Center Cards
                %     app.playedCards = ReadArray(app, 3);
                %     UpdateButtonIcons(app, 1:4, resize(app.playedCards, 4), false)
                % 
                % end
            else
                %% Local Version
                % Game Initialization 
                % app.numPlayers = app.HowManyPlayersEditField.Value;
                
                %How many players are there?
                if (app.numPlayers >= 2) && (app.numPlayers <= 4)
                    fprintf("Number of players accepted! \n")

                    % Generate Card Decks
                    app.gameDeck = randperm(52);
                   
                    app.playerDecks = zeros(app.numPlayers, 13);
                    for i = 1:app.numPlayers
                        app.playerDecks(i, :) = app.gameDeck((13*i - 12): 13*i);
                        UpdateButtonIcons(app, (i*100) + (1:13), app.playerDecks(i,:), false);
                    end
                    
                    fprintf("The following decks have been created: \n")
                    disp(app.playerDecks)
                    changeScene = true;                    
                else
                    fprintf("Number of players not supported \n")
                end
            end

            if changeScene
                % Change Scene
                app.RulesButton.Visible = "on";
                app.LastHandPlayedxLabel.Visible = "on";
                app.WhosUpPlLabel.Visible = "on";
                app.PlayCardsButton.Visible = "on";
                app.SkipTurnButton.Visible = "on";
                app.WhosUpBox.Visible = "on";
                app.PresidentTitle.Visible = "on"; %% keep president image up
                app.PlayButton.Visible = "off";
                app.JoinGameButton.Visible = "off";
                app.HowManyPlayersEditField.Visible ="off";
                app.HowManyPlayersEditFieldLabel.Visible = "off";
                app.ConfirmButton.Visible ="off";
               app.ResetLobbyButton.Visible = "off";
                
                if not(app.playerTurn == app.playerID)
                    app.PlayCardsButton.Visible = "off";
                    app.SkipTurnButton.Visible = "off";
                end
                
                % Show cards for number of players
                switch app.numPlayers
                    case 2
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "off";
                        app.Player4Label.Visible = "off";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "off";
                        app.Player4Box.Visible = "off";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213], "on")
                    case 3
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "on";
                        app.Player4Label.Visible = "off";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "on";
                        app.Player4Box.Visible = "off";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213], "on")
                    case 4
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "on";
                        app.Player4Label.Visible = "on";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "on";
                        app.Player4Box.Visible = "on";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213], "on")
                end
            end

            if app.isOnline
                PlayerTurnLock(app)
            end
        end

        % Button pushed function: PlayCardsButton
        function PlayCardsButtonPushed(app, event)
            %% Initalization
            fprintf("The current player turn is: %g \n", app.playerTurn);
            
            % Check if game is online
            if app.isOnline
                PlayerTurnLock(app)
                % isMyTurn = app.playerTurn == app.playerID;
                % 
                % if ~isMyTurn
                %     app.PlayCardsButton.Visible = "off";
                %     app.SkipTurnButton.Visible = "off";
                %     while ~isMyTurn
                %         app.playerTurn = ReadArray(app, 4);
                %         % app.playerTurn = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', 3);
                %         pause(app.duration)
                %     end
                % 
                %     app.PlayCardsButton.Visible = "on";
                %     app.SkipTurnButton.Visible = "on";
                % 
                %     % Update Center Cards
                %     app.playedCards = ReadArray(app, 3);
                %     UpdateButtonIcons(app, 1:4, resize(app.playedCards, 4), false)
                % 
                % end
            end
            
            % Check button states and make array
            playerButtons = app.playerTurn*100 + (1:13);
            buttonStates = CheckButtonStates(app, playerButtons);
            selectedCards = app.playerDecks(app.playerTurn,buttonStates);           
            
            % Define values to be used for rest of function
            selectedSetSize = length(selectedCards);
            selectedCardValue = mod(selectedCards, 13);
                if selectedCardValue == 0
                    selectedCardValue = 13;
                end 

            playedSetSize = length(app.playedCards);
            playedCardValue = mod(app.playedCards, 13);
                if playedCardValue == 0
                    playedCardValue = 13;
                end
            
            %% Unsucsessful Turn Logic
            % Have card(s) be selected
            if isempty(selectedCards)
                fprintf("Please select at least one card \n")
                return
            end

            % Check if cards played are of matching value
            if selectedSetSize > 1
                for i = 1:(selectedSetSize - 1)
                    if not(selectedCardValue(i) == selectedCardValue(i+1))
                        fprintf("Cards %g are not of matching value \n", selectedCards(:))
                        return
                    end
                end
            end
            
            % Check if number of cards played matches previous play
            if any(app.playedCards) && not(selectedSetSize == playedSetSize)
               disp(selectedSetSize);
               fprintf("The deck of %g does not match the size of the previous play \n", selectedCards)
               return
            end
            
            %% Sucessful Turn Logic
            %Check if cards played are higher value
            if (selectedCardValue(1) > playedCardValue(1)) || ~any(app.playedCards)
                app.playedCards = selectedCards;
                CallImage(app, app.playedCards);
                fprintf("Cards %g played sucessfully! \n", app.playedCards)

                % Kill cards from deck
                position = find(app.playerDecks(app.playerTurn, :) == app.playedCards(:));
                app.playerDecks(app.playerTurn, position) = 0;
                fprintf("The current decks are: \n")
                disp(app.playerDecks);

                % Make Button Disapear
                buttonsOff = playerButtons(buttonStates);
                UpdateButtonVisible(app, buttonsOff, "off")
                UpdateButtonValue(app, buttonsOff, 0)

                % Set Center Buttons and Increase Turn
                UpdateButtonIcons(app, 1:4, resize(app.playedCards, 4), false)
                IncreasePlayerTurn(app);

                %% Check Winstate
                for i = 1:app.numPlayers
                    playersHand = app.playerDecks(i,:); %isolates one player's hand
                    summedHand = sum(playersHand); %sums all the values of that players hand

                    if summedHand == 0  %Checks if hand sums to 0, meaning the hand is empty
                        app.gameState = "Round Complete";
                        fprintf("Player %g has won! \n", i)
                        app.winPlayer = i;
                        break
                    else 
                        app.gameState = "Round Incomplete";
                        fprintf("Player %g has still not won \n", i)
                        app.winPlayer = 0 ;
                    end 
                end 

                % Send to ThingSpeak
                if app.isOnline
                    UpdateThingSpeak(app)
                    PlayerTurnLock(app)
                end
            else
                fprintf("The current value on the table is %g \n", playedCardValue)
                fprintf("Value of cards %g ia not high enough \n", selectedCardValue)
       
            end
        end

        % Button pushed function: SkipTurnButton
        function SkipTurnButtonPushed(app, event)

            %Increase skip Count
            if app.isOnline
                app.skipCount = ReadArray(app, 5);
                % app.skipCount = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', 6);
            end
            app.skipCount = app.skipCount + 1;

            %Check if all players have skip, initialize new round, set next playerTurn
            if app.skipCount == app.numPlayers - 1
                roundWinner = mod(app.turnCount, app.numPlayers) + 1; 
                app.skipCount = 0;
                app.playedCards = 0;
                UpdateButtonIcons(app, 1:4, app.playedCards, true)

                if app.isOnline
                    SendArray(app, 6, app.skipCount);
                    % thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 6, 'Values', app.skipCount);
                    pause(app.duration)
                    SendArray(app, 3, app.playedCards)
                    % thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 3, 'Values', app.playedCards);
                end

                fprintf("Player %g has won the round! \n", roundWinner);
            end
                    
             %Increase Player Turn     
             IncreasePlayerTurn(app);
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on";
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.HomeButton.Visible = "off";
        end

        % Button pushed function: ResetLobbyButton
        function ResetLobbyButtonPushed(app, event)
            % app.lobbyState = thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 1, 'Values', 0);
            % SendArray(app, 1, 0);
            fprintf("Reseting lobby and ThingSpeak...\n")
            app.lobbyState = 0;
            app.gameDeck = 0;
            app.playedCards = 0;
            app.playerTurn = 1;
            app.skipCount = 1;
            UpdateThingSpeak(app)
            % fprintf("Lobby and ThingSpeak reset \n")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Player2Box = uiimage(app.UIFigure);
            app.Player2Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player4Box = uiimage(app.UIFigure);
            app.Player4Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player1Box = uiimage(app.UIFigure);
            app.Player1Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player3Box = uiimage(app.UIFigure);
            app.Player3Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.PresidentTitle = uiimage(app.UIFigure);
            app.PresidentTitle.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'President Title.png');
            app.WhosUpBox = uiimage(app.UIFigure);
            app.WhosUpBox.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background bigger.png');
            app.SkipTurnButton = uibutton(app.UIFigure, 'push');
            app.SkipTurnButton.ButtonPushedFcn = createCallbackFcn(app, @SkipTurnButtonPushed, true);
            app.SkipTurnButton.Text = 'Skip Turn';
            app.PlayCardsButton = uibutton(app.UIFigure, 'push');
            app.PlayCardsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCardsButtonPushed, true);
            app.PlayCardsButton.Text = 'Play Cards';
            app.WhosUpPlLabel = uilabel(app.UIFigure);
            app.WhosUpPlLabel.Text = {'Who''s Up:'; 'Pl'};
            app.LastHandPlayedxLabel = uilabel(app.UIFigure);
            app.LastHandPlayedxLabel.Text = {'Last Hand Played:'; 'x'};
            app.Player4Label = uilabel(app.UIFigure);
            app.Player4Label.Text = 'Player 4';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.JoinGameButton = uibutton(app.UIFigure, 'push');
            app.JoinGameButton.ButtonPushedFcn = createCallbackFcn(app, @JoinGameButtonPushed, true);
            app.JoinGameButton.Text = 'Join Game';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.HowManyPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.HowManyPlayersEditFieldLabel.Text = 'How Many Players?';
            app.HowManyPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.HowManyPlayersEditField.Value = 2;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Button_101 = uibutton(app.UIFigure, 'state');
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.UIFigure, 'state');
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.UIFigure, 'state');
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.UIFigure, 'state');
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.UIFigure, 'state');
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.UIFigure, 'state');
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.UIFigure, 'state');
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.UIFigure, 'state');
            app.Button_108.Text = '';
            app.Button_109 = uibutton(app.UIFigure, 'state');
            app.Button_109.Text = '';
            app.Button_110 = uibutton(app.UIFigure, 'state');
            app.Button_110.Text = '';
            app.Button_111 = uibutton(app.UIFigure, 'state');
            app.Button_111.Text = '';
            app.Button_112 = uibutton(app.UIFigure, 'state');
            app.Button_112.Text = '';
            app.Button_113 = uibutton(app.UIFigure, 'state');
            app.Button_113.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = '';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.Text = '';
            app.Button_201 = uibutton(app.UIFigure, 'state');
            app.Button_201.Text = '';
            app.Button_202 = uibutton(app.UIFigure, 'state');
            app.Button_202.Text = '';
            app.Button_203 = uibutton(app.UIFigure, 'state');
            app.Button_203.Text = '';
            app.Button_204 = uibutton(app.UIFigure, 'state');
            app.Button_204.Text = '';
            app.Button_205 = uibutton(app.UIFigure, 'state');
            app.Button_205.Text = '';
            app.Button_206 = uibutton(app.UIFigure, 'state');
            app.Button_206.Text = '';
            app.Button_207 = uibutton(app.UIFigure, 'state');
            app.Button_207.Text = '';
            app.Button_208 = uibutton(app.UIFigure, 'state');
            app.Button_208.Text = '';
            app.Button_209 = uibutton(app.UIFigure, 'state');
            app.Button_209.Text = '';
            app.Button_210 = uibutton(app.UIFigure, 'state');
            app.Button_210.Text = '';
            app.Button_211 = uibutton(app.UIFigure, 'state');
            app.Button_211.Text = '';
            app.Button_212 = uibutton(app.UIFigure, 'state');
            app.Button_212.Text = '';
            app.Button_213 = uibutton(app.UIFigure, 'state');
            app.Button_213.Text = '';
            app.ResetLobbyButton = uibutton(app.UIFigure, 'push');
            app.ResetLobbyButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLobbyButtonPushed, true);
            app.ResetLobbyButton.Text = 'Reset Lobby';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = President

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end